classdef signal_game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ControlPanel                  matlab.ui.container.Panel
        StatusLabel                   matlab.ui.control.Label
        SubmitButton                  matlab.ui.control.Button
        BERLabel                      matlab.ui.control.Label
        SNRLabel                      matlab.ui.control.Label
        GainSlider                    matlab.ui.control.Slider
        GainSliderLabel               matlab.ui.control.Label
        ThresholdSlider               matlab.ui.control.Slider
        DecisionThresholdSliderLabel  matlab.ui.control.Label
        CarrierFrequencySlider        matlab.ui.control.Slider
        CarrierFrequencySliderLabel   matlab.ui.control.Label
        ModulationDropDown            matlab.ui.control.DropDown
        ModulationTypeDropDownLabel   matlab.ui.control.Label
        NoiseSlider                   matlab.ui.control.Slider
        Label                         matlab.ui.control.Label
        ObjectiveLabel                matlab.ui.control.Label
        LevelLabel                    matlab.ui.control.Label
        FFTAxes                       matlab.ui.control.UIAxes
        OutputAxes                    matlab.ui.control.UIAxes
        InputAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentLevel = 1;
        t
        originalSignal
        corruptedSignal
        outputSignal 
        rawDemodulated
        binaryOriginal
        binaryDetected
        errorSignal
        finalBER
    end
    
    methods (Access = private)
        
        function addNoise(app)

            noiseLevel = app.NoiseSlider.Value;
        
            noise = noiseLevel * randn(size(app.originalSignal));
            app.corruptedSignal = app.originalSignal + noise;
        
            plot(app.InputAxes, app.t, app.corruptedSignal)
            title(app.InputAxes, 'Noisy Signal')
        
        end
        
        function processSignal(app)
            window = round(1 + 20 * app.NoiseSlider.Value);
            app.outputSignal = smoothdata(app.corruptedSignal, 'gaussian', window);
            plot(app.OutputAxes, app.t, app.outputSignal)
            title(app.OutputAxes, 'Recovered Signal')
            updateFFT(app);
            calculateMetrics(app);
        end
        
        function updateFFT(app)
            Y = fft(app.outputSignal);
            f = linspace(0, 1, length(Y));        
            plot(app.FFTAxes, f, abs(Y))
            title(app.FFTAxes, 'Frequency Spectrum')
        end
        
        function calculateMetrics(app)

            signalPower = mean(app.originalSignal.^2);
            noisePower = mean((app.originalSignal - app.outputSignal).^2);
        
            snrValue = 10 * log10(signalPower / noisePower);
        
            app.SNRLabel.Text = sprintf('SNR: %.2f dB', snrValue);
        
            % 🎯 STATUS LOGIC (NEW)
            if snrValue < 5
                status = 'Poor';
            elseif snrValue < 10
                status = 'Moderate';
            elseif snrValue < 15
                status = 'Good';
            else
                status = 'Excellent';
            end
        
            app.StatusLabel.Text = ['Status: ' status];
        
            % Keep BER empty for now
            app.BERLabel.Text = 'BER: --';
        
        end
        
        function loadLevel(app)

            if app.currentLevel == 1
        
                % ✅ LEVEL 1 LABELS
                app.LevelLabel.Text = 'Level: 1';
                app.ObjectiveLabel.Text = 'Objective: Reduce Noise';
        
                % Enable controls
                app.NoiseSlider.Enable = 'on';
                app.NoiseSlider.Value = 0.8;
        
                % Disable others
                app.ModulationDropDown.Enable = 'off';
                app.CarrierFrequencySlider.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.GainSlider.Enable = 'off';
        
                % Signal
                app.t = 0:0.001:1;
                app.originalSignal = sin(2*pi*5*app.t);

                app.ModulationDropDown.Visible = 'off';
                app.ModulationTypeDropDownLabel.Visible = 'off';
        
                addNoise(app);
                processSignal(app);
        
            elseif app.currentLevel == 2
        
                % ✅ LEVEL 2 LABELS
                app.LevelLabel.Text = 'Level: 2';
                app.ObjectiveLabel.Text = 'Objective: Apply Modulation';
        
                % Enable controls
                app.ModulationDropDown.Enable = 'off';
                app.CarrierFrequencySlider.Enable = 'on';
        
                % Disable others
                app.NoiseSlider.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.GainSlider.Enable = 'off';
        
                % Signal
                app.t = 0:0.001:1;
                app.originalSignal = sin(2*pi*5*app.t);

                app.ModulationDropDown.Visible = 'off';
                app.ModulationTypeDropDownLabel.Visible = 'off';

                plot(app.InputAxes, app.t, app.originalSignal)
                title(app.InputAxes, 'Message Signal')
                applyModulation(app);

            elseif app.currentLevel == 3

                % Labels
                app.LevelLabel.Text = 'Level: 3';
                app.ObjectiveLabel.Text = 'Objective: Demodulate Signal';
            
                % Enable controls (simple for now)
                app.GainSlider.Enable = 'on';   % optional tuning
                app.GainSlider.Value = 0.5;   % keep if needed
                % Hide unnecessary
                app.NoiseSlider.Enable = 'off';
            
                % Signal
                app.t = 0:0.001:1;
                app.originalSignal = sin(2*pi*5*app.t);
            
                % Generate AM signal (like Level 2)
                fc = 20;  % fixed carrier for now
                carrier = cos(2*pi*fc*app.t);
            
                app.corruptedSignal = (1 + app.originalSignal) .* carrier;
            
                % Show modulated input
                plot(app.InputAxes, app.t, app.corruptedSignal)
                title(app.InputAxes, 'Received AM Signal')

                app.CarrierFrequencySlider.Enable = 'off';
                app.ModulationDropDown.Visible = 'off';
                app.ModulationTypeDropDownLabel.Visible = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.GainSlider.Enable = 'on'; 
                app.StatusLabel.Text = 'Status: --';
                app.SNRLabel.Text = 'SNR: --';
                app.BERLabel.Text = 'BER: --';


                % Call demodulation
                applyDemodulation(app);

            elseif app.currentLevel == 4

                % Labels
                app.LevelLabel.Text = 'Level: 4';
                app.ObjectiveLabel.Text = 'Objective: Detect Bits & Minimize Errors';
            
                % Controls
                app.ThresholdSlider.Visible = 'on';
                app.ThresholdSlider.Enable = 'on';
               
                app.DecisionThresholdSliderLabel.Visible = 'on';

                app.ThresholdSlider.Limits = [-1 1];
                app.ThresholdSlider.Value = 0.6;   % default starting point
            
                app.GainSlider.Enable = 'off';
                app.NoiseSlider.Enable = 'off';
                app.CarrierFrequencySlider.Enable = 'off';
            
                app.StatusLabel.Text = 'Status: --';
                app.SNRLabel.Text = 'SNR: --';
                app.BERLabel.Text = 'BER: --';
            
                % Signal (use same base signal)
                app.t = 0:0.001:1;
                analog = sin(2*pi*5*app.t);
            
                % Convert original to binary (reference)
                app.binaryOriginal = analog > 0;
            
                % Add noise (to make it challenging)
                noise = 0.2 * randn(size(analog));
                app.corruptedSignal = analog + noise;
            
                % Show input
                plot(app.InputAxes, app.t, app.corruptedSignal)
                title(app.InputAxes, 'Noisy Signal (Detection Stage)')
            
                % Initial detection
                applyDetection(app);

            elseif app.currentLevel == 5

                % Labels
                app.LevelLabel.Text = 'Level: 5';
                app.ObjectiveLabel.Text = 'Objective: Analyze System Performance';
            
                % Hide all controls
                app.NoiseSlider.Enable = 'off';
                app.CarrierFrequencySlider.Enable = 'off';
                app.GainSlider.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
            
                % Keep threshold hidden too
                app.DecisionThresholdSliderLabel.Enable = 'off';
            
                % Status reset
                app.StatusLabel.Text = 'Status: Final Analysis';
                
                % Reuse Level 4 signals
                analog = sin(2*pi*5*app.t);
                app.binaryOriginal = analog > 0;
            
               ber = app.finalBER;

                % Recompute error signal from Level 4 result
                app.errorSignal = app.binaryOriginal ~= app.binaryDetected;
            
                % Plot ORIGINAL
                stairs(app.InputAxes, app.t, app.binaryOriginal, 'LineWidth', 1.5)
                title(app.InputAxes, 'Original Binary Signal')
                ylim(app.InputAxes, [-0.2 1.2])
            
                % Plot DETECTED
                stairs(app.OutputAxes, app.t, app.binaryDetected, 'LineWidth', 1.5)
                title(app.OutputAxes, 'Detected Binary Signal')
                ylim(app.OutputAxes, [-0.2 1.2])
            
                % FFT not needed but keep clean
                cla(app.FFTAxes)
                title(app.FFTAxes, 'Error Locations')
            
                hold(app.FFTAxes, 'on')
                stem(app.FFTAxes, app.t, app.errorSignal, 'r')
                hold(app.FFTAxes, 'off')
                
                app.Label.Enable = 'off';   % Noise label
                app.GainSliderLabel.Enable = 'off';
                app.CarrierFrequencySliderLabel.Enable = 'off';
                app.DecisionThresholdSliderLabel.Enable = 'off';
            
                % Evaluate
                evaluateFinal(app);
   
            end

        end    
        
        function applyModulation(app)

            t = app.t;
            fc = app.CarrierFrequencySlider.Value;
        
            % Carrier
            carrier = cos(2*pi*fc*t);
        
            % Message
            m = app.originalSignal;
        
            % AM Modulation
            app.outputSignal = (1 + m) .* carrier;
        
            % Plot output
            plot(app.OutputAxes, t, app.outputSignal)
            title(app.OutputAxes, 'AM Modulated Signal')
        
            % FFT
            updateFFT(app);
        
            % Status
            evaluateModulation(app);
        
        end
        
        
        function evaluateModulation(app)

            fc = app.CarrierFrequencySlider.Value;
        
            if fc > 10
                app.StatusLabel.Text = 'Proper Modulation';
            else
                app.StatusLabel.Text = 'Increase Carrier Frequency';
            end
        
            app.SNRLabel.Text = 'SNR: --';
            app.BERLabel.Text = 'BER: --';
        
        end
        function applyDemodulation(app)

            % Step 1: Rectify (absolute value)
            rectified = abs(app.corruptedSignal);
        
            % Step 2: Smooth (low-pass filter effect)
            window = round(1 + 30 * app.GainSlider.Value);  

            demodulated = smoothdata(rectified, 'gaussian', window);
        
            gain = app.GainSlider.Value;
            
            app.outputSignal = gain * (demodulated - mean(demodulated));        
            % Plot output
            plot(app.OutputAxes, app.t, app.outputSignal)
            title(app.OutputAxes, 'Recovered Signal')
            app.rawDemodulated = rectified;   % store bad version
            % FFT
            updateFFT(app);
        
            % Evaluate
            evaluateDemodulation(app);
        
        end

        function evaluateDemodulation(app)
            error = mean(abs(app.originalSignal - app.outputSignal));
            
            if error < 0.1
                status = 'Excellent Recovery';
            elseif error < 0.2
                status = 'Good Recovery';
            else
                status = 'Improve Recovery';
            end
        
            app.StatusLabel.Text = ['Status: ' status];
            app.SNRLabel.Text = 'SNR: --';
            app.BERLabel.Text = 'BER: --';
        
        end

        function applyDetection(app)

            threshold = app.ThresholdSlider.Value;
        
            % Detect bits
            app.binaryDetected = app.corruptedSignal > threshold;
        
            % Convert to waveform (for visualization)
            detectedSignal = double(app.binaryDetected);
        
            % Plot output
            stairs(app.OutputAxes, app.t, detectedSignal, 'LineWidth', 1.5)
            ylim(app.OutputAxes, [-0.2 1.2])
            title(app.OutputAxes, 'Detected Binary Signal')
        
            % FFT (optional)
            updateFFT(app);
        
            % Evaluate BER
            evaluateDetection(app);
        
        end

        function evaluateDetection(app)

            % Compare bits
            errors = sum(app.binaryOriginal ~= app.binaryDetected);
            totalBits = length(app.binaryOriginal);
        
            ber = errors / totalBits;
        
            app.BERLabel.Text = sprintf('BER: %.4f', ber);
        
            % Status logic
            if ber < 0.07
                status = 'Excellent Detection';
            elseif ber < 0.15
                status = 'Good Detection';
            else
                status = 'High Error - Adjust Threshold';
            end
        
            app.StatusLabel.Text = ['Status: ' status];
        
        end

        function evaluateFinal(app)

            errors = sum(app.errorSignal);
            totalBits = length(app.errorSignal);
        
            ber = app.finalBER;
        
            app.BERLabel.Text = sprintf('BER: %.4f', ber);
            app.SNRLabel.Text = 'SNR: Final Stage';

            errorText = sprintf('Errors: %d / %d', errors, totalBits);
        
            % Final verdict
            if ber < 0.06
                status = 'Excellent System';
            elseif ber < 0.08
                status = 'Good System';
            elseif ber < 0.1
                status = 'Acceptable System';
            else
                status = 'System Needs Improvement';
            end
            
            app.StatusLabel.Text = ['Status: ' status ' | ' errorText];
        
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = 0:0.001:1;
            app.originalSignal = sin(2*pi*5*app.t);
            
            plot(app.InputAxes, app.t, app.originalSignal)
            title(app.InputAxes, 'Noisy Signal')
            addNoise(app);
            processSignal(app);
            loadLevel(app);
        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
            addNoise(app);
            processSignal(app);
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)

            if app.currentLevel == 1

                snrText = app.SNRLabel.Text;
                snrValue = sscanf(snrText, 'SNR: %f');
        
                if snrValue > 10
                    uialert(app.UIFigure, 'Level 1 Complete!', 'Success');
        
                    % MOVE TO NEXT LEVEL
                    app.currentLevel = 2;
                    loadLevel(app);
        
                else
                    uialert(app.UIFigure, 'Signal not clear enough!', 'Try Again');
                end

            elseif app.currentLevel == 2

                fc = app.CarrierFrequencySlider.Value;
                modType = app.ModulationDropDown.Value;
            
                if strcmp(modType, 'AM') && fc > 10
                    uialert(app.UIFigure, 'Level 2 Complete!', 'Success');
            
                    app.currentLevel = 3;
                    loadLevel(app); 
            
                else
                    uialert(app.UIFigure, 'Incorrect modulation!', 'Try Again');
                end

            elseif app.currentLevel == 3

                error = mean(abs(app.originalSignal - app.outputSignal));
             
                if error < 0.25
                    uialert(app.UIFigure, 'Level 3 Complete!', 'Success');
                    app.currentLevel = 4;
                    loadLevel(app);
                else
                    uialert(app.UIFigure, 'Demodulation not accurate!', 'Try Again');
                end

            elseif app.currentLevel == 4

                berText = app.BERLabel.Text;
                berValue = sscanf(berText, 'BER: %f');
            
                if berValue < 0.1
                    app.finalBER = berValue;
                    uialert(app.UIFigure, 'Level 4 Complete!', 'Success');
                    app.currentLevel = 5;
                    loadLevel(app); 
                else
                    uialert(app.UIFigure, 'Too many errors!', 'Try Again');
                end
              
            elseif app.currentLevel == 5

                berValue = app.finalBER;

                % Grade logic
                if berValue < 0.06
                    grade = 'High reliability system : A+';
                elseif berValue < 0.08
                    grade = 'Moderate reliability : A';
                elseif berValue < 0.1
                    grade = 'Acceptable but noisy: B';
                else
                    grade = 'Poor reliability system : C';
                end
            
                msg = sprintf(['SYSTEM COMPLETE!\n\n' ...
                    'Final BER: %.4f\n' ...
                    'Grade: %s\n\n' ...
                    'This reflects overall communication reliability.'], ...
                    berValue, grade);
            
                uialert(app.UIFigure, msg, 'Final Result');
            
                app.SubmitButton.Enable = 'off';
            
            end


        end

        % Value changed function: CarrierFrequencySlider
        function CarrierFrequencySliderValueChanged(app, event)
            if app.currentLevel == 2
                applyModulation(app);
            end
                    
        end

        % Value changed function: ModulationDropDown
        function ModulationDropDownValueChanged(app, event)
            if app.currentLevel == 2
                applyModulation(app);
            end

            
        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
            if app.currentLevel == 3
                applyDemodulation(app);   % 🔥 THIS IS THE MISSING LINK
            end
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            if app.currentLevel == 4
                applyDetection(app);
            end
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputAxes = uiaxes(app.GridLayout);
            title(app.InputAxes, 'Noisy Signal')
            xlabel(app.InputAxes, 'Time')
            ylabel(app.InputAxes, 'Amplitude')
            zlabel(app.InputAxes, 'Z')
            app.OutputAxes = uiaxes(app.GridLayout);
            title(app.OutputAxes, 'Recovered Signal')
            xlabel(app.OutputAxes, 'Time')
            ylabel(app.OutputAxes, 'Amplitude')
            zlabel(app.OutputAxes, 'Z')
            app.FFTAxes = uiaxes(app.GridLayout);
            title(app.FFTAxes, 'Frequency Spectrum')
            xlabel(app.FFTAxes, 'Time')
            ylabel(app.FFTAxes, 'Amplitude')
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.LevelLabel = uilabel(app.ControlPanel);
            app.LevelLabel.Text = 'Level: 1';
            app.ObjectiveLabel = uilabel(app.ControlPanel);
            app.ObjectiveLabel.Text = {'Objective: '; 'Reduce Noise'};
            app.Label = uilabel(app.ControlPanel);
            app.Label.Text = 'Noise Level';
            app.NoiseSlider = uislider(app.ControlPanel);
            app.NoiseSlider.Limits = [0 1];
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.NoiseSlider.Value = 0.2;
            app.ModulationTypeDropDownLabel = uilabel(app.ControlPanel);
            app.ModulationTypeDropDownLabel.Text = 'Modulation Type';
            app.ModulationDropDown = uidropdown(app.ControlPanel);
            app.ModulationDropDown.Items = {'AM', 'FM', 'ASK', 'FSK'};
            app.ModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationDropDownValueChanged, true);
            app.ModulationDropDown.Value = 'AM';
            app.CarrierFrequencySliderLabel = uilabel(app.ControlPanel);
            app.CarrierFrequencySliderLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencySlider = uislider(app.ControlPanel);
            app.CarrierFrequencySlider.Limits = [1 50];
            app.CarrierFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @CarrierFrequencySliderValueChanged, true);
            app.CarrierFrequencySlider.Value = 1;
            app.DecisionThresholdSliderLabel = uilabel(app.ControlPanel);
            app.DecisionThresholdSliderLabel.Text = {'Decision'; 'Threshold'};
            app.ThresholdSlider = uislider(app.ControlPanel);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.GainSliderLabel = uilabel(app.ControlPanel);
            app.GainSliderLabel.Text = 'Gain';
            app.GainSlider = uislider(app.ControlPanel);
            app.GainSlider.Limits = [0.5 5];
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            app.GainSlider.Value = 0.5;
            app.SNRLabel = uilabel(app.ControlPanel);
            app.SNRLabel.Text = 'SNR: --';
            app.BERLabel = uilabel(app.ControlPanel);
            app.BERLabel.Text = 'BER: --';
            app.SubmitButton = uibutton(app.ControlPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.StatusLabel = uilabel(app.ControlPanel);
            app.StatusLabel.Text = 'Status: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end